classdef AutoMacroGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ReadyLabel                     matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
        SolidworksMacroButtonGroup     matlab.ui.container.ButtonGroup
        OtherButton                    matlab.ui.control.RadioButton
        Export_Key_Drawing_DataButton  matlab.ui.control.RadioButton
        Export_Class_Drawing_DataButton  matlab.ui.control.RadioButton
        SolidworksVisibilityButtonGroup  matlab.ui.container.ButtonGroup
        OffButton                      matlab.ui.control.RadioButton
        OnButton                       matlab.ui.control.RadioButton
        RunMacroButton                 matlab.ui.control.Button
        HTML3                          matlab.ui.control.HTML
        HTML                           matlab.ui.control.HTML
        SelectFolderButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        lampState = 0 % Colour and State of the lamp (0 is Ready, 1 is Busy, 2 is Waiting on user)
       
        
        studentFolder = ''; 


        moduleName = "Export_DRW_and_SM_vEclass1";
        
    end
    properties (Access = public)
        macro = fullfile(cd, 'source', "Export_DRW_and_SM_vEclass.swp"); % defaults
        visible = true; % solidworks visibility
    end
    methods (Access = public)
    % same As in makring gui        
        function logOutput(app, newText, errorCode)
            if(nargin < 3)
                errorCode = 0;
            end
            newText = string(newText);
            newText = newText + int2str(errorCode);
            % Move everything down
            %app.OutputLogLatestonTopTextArea.Value(2:end) = app.OutputLogLatestonTopTextArea.Value(1:end-1);
            % Add a new comment
            %app.OutputLogLatestonTopTextArea.Value(1) = {char(newText)
            % this is input to OutputLog.html which has a js function that
            % inserts to html elements into the message log
            app.HTML3.Data = newText;
            
            % Also drawnow, this prevents us from hanging the event loop
            % Bad to call it so often, but its okay.
            drawnow % dont really know if this is neccesarry
        end
    end
    
    methods (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, studentFolder)
            app.studentFolder = studentFolder;  % studentFolder passed in MarkingGUI
        % link html
            app.UIFigure.Name = "AUTOMACRO";
            app.HTML.Data = '';
            app.HTML.HTMLSource = fullfile("source\GUI\AutoMacroGUI\StudentFolders.html");
            app.HTML3.HTMLSource = fullfile("source\GUI\AutoMacroGUI\OutputLog.html");
            drawnow;
            
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)

            % Set GUI to go into directory one level above student folder
            % If no student folder, load into working directory
            oneFolderAboveStud = '';
            if ~strcmp(app.studentFolder, '')
                backSlashLocations = strfind(app.studentFolder, '\');
                lastBackSlash = backSlashLocations(end);
                oneFolderAboveStud = app.studentFolder(1:lastBackSlash);
            end

            getDirectory = uigetdir(oneFolderAboveStud, "Please Select a Student or Key Folder");
            
            if ~isequal(getDirectory, 0)
                % Got something
                
                app.HTML.Data = getDirectory;
            end           
            figure(app.UIFigure);
        end

        % Button pushed function: RunMacroButton
        function RunMacroButtonPushed(app, event)
        %%try
            app.ReadyLamp.Color =  [232, 119, 14]/255;
            app.ReadyLabel.Text = "Starting Extraction";  % Orange
            % calls runMacros file  
            swApp = runMacros(app.HTML.Data, app.macro, app.moduleName, app);
            app.ReadyLabel.Text = "Ready";
            app.ReadyLamp.Color = [14, 237, 14]/255; 
            app.logOutput("Job complete", 2);
        %%catch
            %%app.logOutput("Process Failed unknown error", 1);
        %%end
        try
            swApp.invoke('ExitApp');
        catch
        end
        end

        % Selection changed function: SolidworksVisibilityButtonGroup
        function SolidworksVisibilityButtonGroupSelectionChanged(app, event)
            selectedButton = app.SolidworksVisibilityButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Off')
                app.visible = false;
            else
                app.visible = true;
            end
        end

        % Selection changed function: SolidworksMacroButtonGroup
        function SolidworksMacroButtonGroupSelectionChanged(app, event)
            selectedButton = app.SolidworksMacroButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Export_Class_Drawing_Data')
                app.macro = fullfile(cd, 'source', "Export_DRW_and_SM_vEclass.swp");
                app.moduleName = "Export_DRW_and_SM_vEclass1";
            elseif strcmp(selectedButton.Text, 'Other')
                app.macro = uigetfile('*.swp', "Please select the macro");
                figure(app.UIFigure);
                inputCell = inputdlg('Please enter the module name to run');
                if isempty(inputCell)
                    app.macro = inputCell{1};
                else
                    % if they cancel
                    app.macro = fullfile(cd, 'source', "Export_DRW_and_SM_vEclass.swp");
                    app.moduleName = "Export_DRW_and_SM_vEclass1";
                end
            else
                % only other option
                app.macro = fullfile(cd, 'source', "Export_DRW_and_SM_v6.swp");
                app.moduleName = "Export_DRW_and_SM_v61";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Tooltip = {'Select a student Folder'};
            app.SelectFolderButton.Text = 'Select Folder';
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '{}';
            app.HTML.Data = 'app.HTML.HTMLSource';
            app.HTML3 = uihtml(app.UIFigure);
            app.RunMacroButton = uibutton(app.UIFigure, 'push');
            app.RunMacroButton.ButtonPushedFcn = createCallbackFcn(app, @RunMacroButtonPushed, true);
            app.RunMacroButton.Text = 'Run Macro';
            app.SolidworksVisibilityButtonGroup = uibuttongroup(app.UIFigure);
            app.SolidworksVisibilityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SolidworksVisibilityButtonGroupSelectionChanged, true);
            app.SolidworksVisibilityButtonGroup.Title = 'Solidworks Visibility';
            app.OnButton = uiradiobutton(app.SolidworksVisibilityButtonGroup);
            app.OnButton.Text = 'On';
            app.OnButton.Value = true;
            app.OffButton = uiradiobutton(app.SolidworksVisibilityButtonGroup);
            app.OffButton.Text = 'Off';
            app.SolidworksMacroButtonGroup = uibuttongroup(app.UIFigure);
            app.SolidworksMacroButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SolidworksMacroButtonGroupSelectionChanged, true);
            app.SolidworksMacroButtonGroup.Title = 'Solidworks Macro';
            app.Export_Class_Drawing_DataButton = uiradiobutton(app.SolidworksMacroButtonGroup);
            app.Export_Class_Drawing_DataButton.Text = 'Export_Class_Drawing_Data';
            app.Export_Class_Drawing_DataButton.Value = true;
            app.Export_Key_Drawing_DataButton = uiradiobutton(app.SolidworksMacroButtonGroup);
            app.Export_Key_Drawing_DataButton.Text = 'Export_Key_Drawing_Data';
            app.OtherButton = uiradiobutton(app.SolidworksMacroButtonGroup);
            app.OtherButton.Text = 'Other';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.ReadyLabel = uilabel(app.UIFigure);
            app.ReadyLabel.Text = 'Ready';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutoMacroGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end