classdef MarkingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RunMarkingon1StudentButton    matlab.ui.control.Button
        Image2                        matlab.ui.control.Image
        TextArea                      matlab.ui.control.TextArea
        RunMarkingButton              matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        AutoMarkv60Label              matlab.ui.control.Label
        RunSolidworksMacroButton      matlab.ui.control.Button
        HTML                          matlab.ui.control.HTML
        StatusLamp                    matlab.ui.control.Lamp
        StatusLabel                   matlab.ui.control.Label
        OutputLogLatestonTopTextArea  matlab.ui.control.TextArea
        OutputLogLatestonTopLabel     matlab.ui.control.Label
        FolderDisplay                 matlab.ui.control.TextArea
        ClearButton                   matlab.ui.control.Button
        SelectStudentFolderButton     matlab.ui.control.Button
        ModifyTemplateButton          matlab.ui.control.Button
        MakeTemplateButton            matlab.ui.control.Button
        LoadTemplateButton            matlab.ui.control.Button
        RunMacrosandMarkingButton     matlab.ui.control.Button
        RepackEclassZipButton         matlab.ui.control.Button
        UnpackEclassZipButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        lampState = 0 % Colour and State of the lamp (0 is Ready, 1 is Busy, 2 is Waiting on user)
        loglines = 0; 
        
        studentFolder = '';
        templateFile = '';
        
        keyTemplate = [];
       
    end
    properties (Access = public)
        visible = false; % solidworks visibility
        settings = {}; % settings for report
    end
    methods (Access = private)
        function updateLamp(app)
            switch app.lampState
                case 0 % Ready
                    app.StatusLabel.Text = "Ready";
                    app.StatusLamp.Color = [14, 237, 14]/255;   % Green
                case 1 % Busy
                    app.StatusLabel.Text = "Busy";
                    app.StatusLamp.Color = [237, 14, 14]/255;   % Red
                case 2 % Waiting on User
                    app.StatusLabel.Text = sprintf("Waiting\nOn\nUser");
                    app.StatusLamp.Color = [232, 119, 14]/255;  % Orange
                otherwise % Errored
                    app.StatusLabel.Text = "ERROR";
                    app.StatusLamp.Color = [151, 16, 230]/255;   % Purple
            end
            drawnow % Nice and responsive!
        end
        
        function lampReady(app)
            app.lampState = 0;
            app.updateLamp();
        end
        
        function lampBusy(app)
            app.lampState = 1;
            app.updateLamp();
            % Likely about to become unresponsive
        end
        
        function lampWait(app)
            app.lampState = 2;
            app.updateLamp();
        end
        
        function lampError(app)
            app.lampState = 3;
            app.updateLamp();
        end
        
        function updateFolderDisplay(app)
            app.FolderDisplay.Value = {'Current Student Folder:';
                '<None>';
                'Current Template:';
                '<None>'};
            if app.studentFolder ~= ""
                app.FolderDisplay.Value(2) = {app.studentFolder};
            end
            if app.templateFile ~= ""
                app.FolderDisplay.Value(4) = {app.templateFile};
            end
        end
        
        
        function saveTemplate(app)
            app.logOutput('Saving template', 0);
        
            if isempty(app.keyTemplate)
                app.logOutput('Nothing to save', 1);
                return;
            end

            % Set GUI to go into directory one level above student folder
            % If no student folder, load into working directory
            oneFolderAboveStud = '';
            if ~strcmp(app.studentFolder, '')
                backSlashLocations = strfind(app.studentFolder, '\');
                lastBackSlash = backSlashLocations(end);
                oneFolderAboveStud = app.studentFolder(1:lastBackSlash);
            end
            
            % Save as
            app.lampWait();
            [templatefile, templatepath] = uiputfile('.mat', "Save Template As", [oneFolderAboveStud, 'keytemplate.mat']);
            
            if isequal(templatefile, 0) || isequal(templatepath, 0)
                app.logOutput('Cancelled', 1)
                app.lampReady()
                return;
            end
            templatefullpath = fullfile(templatepath, templatefile);
            
            app.lampBusy();
            
            drawingkeyTemplate = app.keyTemplate;
            % Save it
            save(templatefullpath, 'drawingkeyTemplate');
            app.templateFile = templatefullpath;
            
            app.updateFolderDisplay;
            
            app.logOutput(sprintf('Saved to "%s"', app.templateFile), 2);
            app.lampReady();
            figure(app.UIFigure); % pushes figure to the front was v annoying before this was here
        end 


        function sendEmail(app, excelFile, logFileName)
            
            if(length(app.settings) >= 5)
                app.logOutput(app.settings{5});
                if ~(strcmp(app.settings{5}, ""))
                    try 
                        % go to sendMail.m if you can format the email
                        % nicer that would be good
                        sendMail(app.settings{5}, 'Marking Report ', sprintf(['To, whomever it may concern\nThe attached documents'...
                           ' contain personal information do not open them if you were not expecting this email.'...
                           '\nThe marking has completed, attached are the marking report and marking log.']), {excelFile, logFileName});
                        app.logOutput(sprintf("Email Notification sent to %s", app.settings{5}), 2);
                    catch
                        app.logOutput(sprintf("Unable to send email to address: %s", app.settings{5}), 1);
                    end
                end
            end
        end
    end
    
    methods (Access = public)
        
        function logOutput(app, newText, errorCode)
            if(nargin < 3)
                errorCode = 0;
            end
            newText = string(newText);
            newText = newText + int2str(errorCode);
            % Move everything down
            %app.OutputLogLatestonTopTextArea.Value(1) = {char(newText)
            % this is input to OutputLog.html which has a js function that
            % inserts to html elements into the message log
            app.HTML.Data = newText;
            
            % Also drawnow, this prevents us from hanging the event loop
            % Bad to call it so often, but its okay.
            drawnow
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global ACTIVEMARKINGAPP
            ACTIVEMARKINGAPP = app;
            app.UIFigure.Name = "AutoMARK";
            app.updateFolderDisplay();
            app.lampReady;
            app.settings = getSettingsArray(); % go to getSettingsArray.m
            app.HTML.HTMLSource = fullfile("source\GUI\MarkingGUI\OutputLog.html");
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.logOutput("Clear");
        end

        % Callback function
        function LinesSpinnerValueChanged(app, event)
            % Make sure its a integer!
            app.LinesSpinner.Value = floor(app.LinesSpinner.Value);
            if app.loglines ~= app.LinesSpinner.Value
                app.resizeOutputLog(app.LinesSpinner.Value);
            end
        end

        % Button pushed function: SelectStudentFolderButton
        function SelectStudentFolderButtonPushed(app, event)
            app.lampWait();
            
            getDirectory = uigetdir('', "Please Select a Student Folder");
            
            if ~isequal(getDirectory, 0)
                % Got something
                
                app.logOutput(sprintf('Selected "%s" as the student folder', getDirectory), 0);
                app.studentFolder = getDirectory;
            else
                app.logOutput('No Student Folder Selected', 1);
            end
            figure(app.UIFigure);
            app.updateFolderDisplay();
            app.lampReady();
        end

        % Button pushed function: UnpackEclassZipButton
        function UnpackEclassZipButtonPushed(app, event)
            app.logOutput("Unpacking!", 0);
            
            app.lampWait();

            % Set GUI to go into directory one level above student folder
            % If no student folder, load into working directory
            oneFolderAboveStud = '';
            if ~strcmp(app.studentFolder, '')
                backSlashLocations = strfind(app.studentFolder, '\');
                lastBackSlash = backSlashLocations(end);
                oneFolderAboveStud = app.studentFolder(1:lastBackSlash);
            end
            
            [eclasszip, eclasszippath] = uigetfile('*.zip', "Please select the zip file to unpack", oneFolderAboveStud);
            figure(app.UIFigure);
            if isequal(eclasszip, 0) || isequal(eclasszippath, 0)
                app.logOutput('No zip file selected!', 1)
                app.lampReady()
                return;
            end
            eclassfullpath = fullfile(eclasszippath, eclasszip);
            app.logOutput(sprintf('Selected "%s"', eclassfullpath), 0);
            
            % If no student folder
            if app.studentFolder == ""
                app.SelectStudentFolderButtonPushed();
            end
            
            % If still no student folder we have problems
            if app.studentFolder == ""
                app.lampReady();
                return
            end
            
            app.lampBusy();
            ReadyEclassZip(eclassfullpath, app.studentFolder);
            app.logOutput("Unpacked! Please run the VBA script on the directory!", 2)
            app.lampReady();
        end

        % Button pushed function: RepackEclassZipButton
        function RepackEclassZipButtonPushed(app, event)
            app.logOutput("Repacking!", 0);
            
            % If no student folder
            if app.studentFolder == ""
                app.SelectStudentFolderButtonPushed();
            end
            
            % If still no student folder we have problems
            if app.studentFolder == ""
                return
            end
            
            % Handle saving files
            app.lampWait();
            [eclasszip, eclasszippath] = uiputfile('eclassoutput.zip', "Save Eclass Zip As");
            
            if isequal(eclasszip, 0) || isequal(eclasszippath, 0)
                app.logOutput('Cancelled', 0)
                app.lampReady()
                return;
            end
            eclassfullpath = fullfile(eclasszippath, eclasszip);
            app.logOutput(sprintf('Saving to "%s"', eclassfullpath), 0);
            
            app.lampBusy();
            RepackEclassZip(app.studentFolder, eclassfullpath,app);
            app.logOutput("Repacked! Zip file is ready for upload!", 2)
            app.lampReady();
        end

        % Button pushed function: LoadTemplateButton
        function LoadTemplateButtonPushed(app, event)
            app.logOutput('Loading a key template', 0);
            app.lampWait();

            % Set GUI to go into directory one level above student folder
            % If no student folder, load into working directory
            oneFolderAboveStud = '';
            if ~strcmp(app.studentFolder, '')
                backSlashLocations = strfind(app.studentFolder, '\');
                lastBackSlash = backSlashLocations(end);
                oneFolderAboveStud = app.studentFolder(1:lastBackSlash);
            end
            
            [templatefile, templatepath] = uigetfile('*.mat', "Please select a valid template file", oneFolderAboveStud);
            if isequal(templatefile, 0) || isequal(templatepath, 0)
                app.logOutput('Cancelled', 1);
                app.lampReady();
                return;
            end
            
            app.lampBusy();
            templatefullpath = fullfile(templatepath, templatefile);
            
            loadedVariables = load(templatefullpath, 'drawingkeyTemplate');
            
            % Verify that this actually has "drawingkeyTemplate" in it
            if ~isfield(loadedVariables, 'drawingkeyTemplate')
                app.logOutput('Not a valid template file', 1);
                app.lampReady();
                return;
            end
            
            % If it does, Make sure that the images for this template can
            % actually be found!
            [isValid, madeChanges] = validateMarkingTemplate(loadedVariables.drawingkeyTemplate);
            if ~isValid
                app.logOutput('Couldn''t verfiy template', 1);
                app.lampReady();
                return;
            end
            
            app.keyTemplate = loadedVariables.drawingkeyTemplate;
            if ~madeChanges
                app.templateFile = templatefullpath;
            else
                app.templateFile = '<UNSAVED TEMPLATE>'; % You hotpatched the template, its not saved!
            end
            app.updateFolderDisplay();
            figure(app.UIFigure);
            app.logOutput(sprintf("Loaded template, created: %s, modified: %s", string(app.keyTemplate.createdDate), string(app.keyTemplate.editDate)), 0);
            app.lampReady();
        end

        % Button pushed function: MakeTemplateButton
        function MakeTemplateButtonPushed(app, event)
            app.logOutput('Making a new template', 0);
            app.lampWait();

            % Set GUI to go into directory one level above student folder
            % If no student folder, load into working directory
            oneFolderAboveStud = '';
            if ~strcmp(app.studentFolder, '')
                backSlashLocations = strfind(app.studentFolder, '\');
                lastBackSlash = backSlashLocations(end);
                oneFolderAboveStud = app.studentFolder(1:lastBackSlash);
            end
            
            [excelFile, excelPath] = uigetfile("*.xlsx", "Please select the excel file output from the key drawing", oneFolderAboveStud);
            figure(app.UIFigure);
            if isequal(excelFile, 0) || isequal(excelPath, 0)
                app.logOutput('Cancelled', 1);
                app.lampReady();
                return;
            end
            
            app.lampBusy();
            excelfullpath = fullfile(excelPath, excelFile);
            app.logOutput(sprintf('Making template from "%s"', excelfullpath), 0);
            
            keydrawing = makeComparableDrawing(excelfullpath);
            app.keyTemplate = makeTemplatefromKey(keydrawing);
            
            app.templateFile = '<UNSAVED TEMPLATE>';
            app.updateFolderDisplay();
            app.logOutput('Success!', 2);
            app.lampReady();
            app.saveTemplate();
        end

        % Button pushed function: ModifyTemplateButton
        function ModifyTemplateButtonPushed(app, event)
            app.logOutput('Modifying Template');
            
            % If we are empty, prompt to make a new template
            if isempty(app.keyTemplate)
                app.MakeTemplateButtonPushed();
            end
            
            % If that fails, we are done
            if isempty(app.keyTemplate)
                return;
            end
            
            % Launch the TemplateGUI app
            
            tempGUI = MarkingTemplateGUI(app, app.keyTemplate, app.studentFolder);
            app.templateFile = '<MODIFIED TEMPLATE>';
            app.updateFolderDisplay();
            
            app.logOutput('Please do your modifications on the Template GUI');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % Just quickly prompt to save the template.
            
            if strcmp(app.templateFile, '<MODIFIED TEMPLATE>') || strcmp(app.templateFile, '<UNSAVED TEMPLATE>')
                app.saveTemplate();
            end
        
            delete(app)
        end

        % Button pushed function: RunSolidworksMacroButton
        function RunSolidworksMacroButtonPushed(app, event)
            AutoMacroGUI(app.studentFolder);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            SettingsGUI;
        end

        % Button pushed function: RunMacrosandMarkingButton
        function RunMacrosandMarkingButtonPushed2(app, event)
            app.lampBusy()
            
            doreturn = false;
            app.settings = getSettingsArray();
            if isempty(app.keyTemplate)
                app.logOutput('No template file', 1);
                doreturn = true;
            end
            
            if app.studentFolder == ""
                app.logOutput('No Student folder selected', 1);
                doreturn = true;
            end
            
            if doreturn
                app.lampReady();
                return;
            end
            % running macros
            app.logOutput(sprintf('Extracting Students in "%s"', app.studentFolder, app.templateFile), 0);
            runMacros(app.studentFolder, fullfile(cd, 'source', "Export_DRW_and_SM_vEclass.swp"),...
                "Export_DRW_and_SM_vEclass1", app);
            app.logOutput(sprintf('Marking Students in "%s" using "%s" as a template', app.studentFolder, string(app.templateFile)), 0);
            % marking
            [excelFile, logFileName] = executeMarkingOnFolder(app.keyTemplate, app.studentFolder, app);
            app.logOutput('Done! If nothing happened or this was instant the VBA likely was not run!', 2);
            app.sendEmail(excelFile, logFileName);
            app.lampReady();
        end

        % Button pushed function: RunMarkingButton
        function RunMarkingButtonPushed(app, event)
            app.settings = getSettingsArray();
            app.lampBusy();
            
            doReturn = false;
            
            if isempty(app.keyTemplate)
                app.logOutput('No template file', 1);
                doReturn = true;
            end
            
            if app.studentFolder == ""
                app.logOutput('No Student folder selected', 1);
                doReturn = true;
            end
            
            if doReturn
                app.lampReady();
                return;
            end
            
            app.logOutput(sprintf('Marking Students in "%s" using "%s" as a template', app.studentFolder, string(app.templateFile)), 0);
            
            [excelFile, logFileName] = executeMarkingOnFolder(app.keyTemplate, app.studentFolder, app);
            
            app.logOutput('Done! If nothing happened or this was instant the VBA likely was not run!', 2);
            app.sendEmail(excelFile, logFileName);
            app.lampReady();   
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            infoGUI();
        end

        % Button pushed function: RunMarkingon1StudentButton
        function RunMarkingon1StudentButtonPushed(app, event)
            app.settings = getSettingsArray();
            app.lampBusy();
            
            % check if no key template, if yes then print message & return
            doReturn = false;
            if isempty(app.keyTemplate)
                app.logOutput('No template file', 1);
                doReturn = true;
            end
            if doReturn
                app.lampReady();
                return;
            end
            
            % prompt user to select a student's submission folder
            subFolder = uigetdir(app.studentFolder, "Please select the student submission folder to mark");
            
            if ~isequal(subFolder, 0)
                % Got something
                if contains(subFolder, app.studentFolder) && ~strcmp(subFolder, app.studentFolder)
                    % selected folder is a subfolder of the Student Folder
                    app.logOutput(sprintf('Marking on folder "%s"', subFolder, 0));
                    markSingleSub(subFolder, app.keyTemplate, app);  % actually marking the submission
                    app.logOutput('Done! If nothing happened or this was instant the VBA likely was not run!', 2);
                else
                    % selected folder is not a subfolder of the Student
                    % Folder
                    app.logOutput('Invalid file selection, please select one of the folders within the Student Folder', 1);
                end
            else
                % no file was selected
                app.logOutput('No Student Submission Folder Selected', 1);
            end
            
            figure(app.UIFigure);
            app.lampReady(); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UnpackEclassZipButton = uibutton(app.UIFigure, 'push');
            app.UnpackEclassZipButton.ButtonPushedFcn = createCallbackFcn(app, @UnpackEclassZipButtonPushed, true);
            app.UnpackEclassZipButton.Tooltip = {'Unzips the zip file from eclass into the student folder.'; 'Prompts for the student folder if not already selected.'};
            app.UnpackEclassZipButton.Text = {'Unpack Eclass Zip'; '(Import)'};
            app.RepackEclassZipButton = uibutton(app.UIFigure, 'push');
            app.RepackEclassZipButton.ButtonPushedFcn = createCallbackFcn(app, @RepackEclassZipButtonPushed, true);
            app.RepackEclassZipButton.Tooltip = {'Repacks the student folder using all files found in the EclassExport folders.'; 'Prompts for the student folder if not already selected.'};
            app.RepackEclassZipButton.Text = {'Repack Eclass Zip'; '(Export)'};
            app.RunMacrosandMarkingButton = uibutton(app.UIFigure, 'push');
            app.RunMacrosandMarkingButton.ButtonPushedFcn = createCallbackFcn(app, @RunMacrosandMarkingButtonPushed2, true);
            app.RunMacrosandMarkingButton.Tooltip = {'Run marking on the Current student folder'; ' using the Current Template.'};
            app.RunMacrosandMarkingButton.Text = 'Run Macros and Marking';
            app.LoadTemplateButton = uibutton(app.UIFigure, 'push');
            app.LoadTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTemplateButtonPushed, true);
            app.LoadTemplateButton.Tooltip = {'Load a template from a .mat file'};
            app.LoadTemplateButton.Text = 'Load Template';
            app.MakeTemplateButton = uibutton(app.UIFigure, 'push');
            app.MakeTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @MakeTemplateButtonPushed, true);
            app.MakeTemplateButton.Tooltip = {'Makes a new template'; ' select a .xlsx file in order to do so!'};
            app.MakeTemplateButton.Text = 'Make Template';
            app.ModifyTemplateButton = uibutton(app.UIFigure, 'push');
            app.ModifyTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyTemplateButtonPushed, true);
            app.ModifyTemplateButton.Tooltip = {'Use the template modifer to change the marking scheme and tolerances.'};
            app.ModifyTemplateButton.Text = {'Modify Template'; '(Opens Template APP)'};
            app.SelectStudentFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectStudentFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectStudentFolderButtonPushed, true);
            app.SelectStudentFolderButton.Tooltip = {'Select a student Folder'};
            app.SelectStudentFolderButton.Text = 'Select Student Folder';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Tooltip = {'Clear the output log'};
            app.ClearButton.Text = 'Clear';
            app.FolderDisplay = uitextarea(app.UIFigure);
            app.FolderDisplay.Editable = 'off';
            app.FolderDisplay.Value = {'Current Student Folder:'; '<None>'; 'Current Template:'; '<None>'};
            app.OutputLogLatestonTopLabel = uilabel(app.UIFigure);
            app.OutputLogLatestonTopLabel.Text = 'Output Log (Latest on Top)';
            app.OutputLogLatestonTopTextArea = uitextarea(app.UIFigure);
            app.OutputLogLatestonTopTextArea.Editable = 'off';
            app.OutputLogLatestonTopTextArea.Tooltip = {'Most recent output at the top'};
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.StatusLamp.Tooltip = {'Status of the application'};
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '{}';
            app.HTML.Data = 'app.HTML.HTMLSource';
            app.RunSolidworksMacroButton = uibutton(app.UIFigure, 'push');
            app.RunSolidworksMacroButton.ButtonPushedFcn = createCallbackFcn(app, @RunSolidworksMacroButtonPushed, true);
            app.RunSolidworksMacroButton.Tooltip = {'Select a student Folder'};
            app.RunSolidworksMacroButton.Text = 'Run Solidworks Macro';
            app.AutoMarkv60Label = uilabel(app.UIFigure);
            app.AutoMarkv60Label.Text = 'AutoMark v 6.0';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'settings.png';
            app.RunMarkingButton = uibutton(app.UIFigure, 'push');
            app.RunMarkingButton.ButtonPushedFcn = createCallbackFcn(app, @RunMarkingButtonPushed, true);
            app.RunMarkingButton.Tooltip = {'Run marking on the Current student folder'; ' using the Current Template.'};
            app.RunMarkingButton.Text = 'Run Marking';
            app.TextArea = uitextarea(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'info.png';
            app.RunMarkingon1StudentButton = uibutton(app.UIFigure, 'push');
            app.RunMarkingon1StudentButton.ButtonPushedFcn = createCallbackFcn(app, @RunMarkingon1StudentButtonPushed, true);
            app.RunMarkingon1StudentButton.Tooltip = {'Run marking on the Current student folder'; ' using the Current Template.'};
            app.RunMarkingon1StudentButton.Text = 'Run Marking on 1 Student';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MarkingGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end