classdef MarkingTemplateGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Button3                        matlab.ui.control.Button
        Button2                        matlab.ui.control.Button
        Button1                        matlab.ui.control.Button
        ColSheetsButton                matlab.ui.control.Button
        ExpSheetsButton                matlab.ui.control.Button
        ExpDrawingsButton              matlab.ui.control.Button
        ColDrawingsButton              matlab.ui.control.Button
        UpdateTreeButton               matlab.ui.control.Button
        CreateAssignmentSummaryButton  matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        yminEditField                  matlab.ui.control.NumericEditField
        ymaxLabel                      matlab.ui.control.Label
        xmaxLabel                      matlab.ui.control.Label
        xminEditField                  matlab.ui.control.NumericEditField
        yminLabel                      matlab.ui.control.Label
        xminLabel                      matlab.ui.control.Label
        rLabel                         matlab.ui.control.Label
        tLabel                         matlab.ui.control.Label
        tEditField                     matlab.ui.control.NumericEditField
        rEditField                     matlab.ui.control.NumericEditField
        yEditField                     matlab.ui.control.NumericEditField
        xEditField                     matlab.ui.control.NumericEditField
        HTML                           matlab.ui.control.HTML
        Tree2                          matlab.ui.container.Tree
        ActiveCriterionNode            matlab.ui.container.TreeNode
        Tree                           matlab.ui.container.Tree
        FeatureWeightEditField         matlab.ui.control.NumericEditField
        FeatureWeightEditFieldLabel    matlab.ui.control.Label
        CriterionTextArea              matlab.ui.control.TextArea
        CriterionWeightEditField       matlab.ui.control.NumericEditField
        CriterionWeightEditFieldLabel  matlab.ui.control.Label
        DisableCriterionCheckBox       matlab.ui.control.CheckBox
        ScaleButton                    matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Template we are marking, its just a variable
        keyTemplate = markingTemplate.empty();
        criterionColours = criterionColoursClass();
        
        % saving student folder to put easily save files
        studentFolder = '';
        
        % Image handlers for each sheet
        imageHandlers = imageHandler2.empty();
        
        % Template iterator which allows us to have state on templates
        templateIterator = simpleTemplateIterator.empty();
        
        % Which criterion of the criterions in the current template cell
        % are we looking at, min 1, even if 0 criterions.
        selectedCriterion = 1;
        
        % Which sheet handler is currently being used
        currentSheetHandler = [];
        
        % Temp graphs objects currently being displayed (delete after any
        % updates).
        currentGraphics = gobjects(0);
        
        % Points pressed while alt was pressed
        pointsClicked = double.empty(0,2);
    end
    
    methods (Access = private)
        function setupImages(app)
            % Initial setup of figure
            keyDrawing = app.keyTemplate.drawing;
            
            xmin = 0;
            
            for i = 1:keyDrawing.numsheets
                sheet = keyDrawing.childsheets(i);
                
                % I put everything in an inches determined box
                bottomleft = [xmin, 0];
                topright = bottomleft + [sheet.width, sheet.height]*100/2.54;
                
                % Pale Cyan background, won't be seen anyway.
                app.imageHandlers(i) = imageHandler2(sheet, bottomleft, topright, [179, 200, 201]./255, app.UIAxes);
                
                app.imageHandlers(i).displayFully();
                % Move over with 1 inch padding
                xmin = xmin + 1 + sheet.width*100/2.54;
            end
            
            % Now add a drawing picture, we put this one in at [-18, 0] to
            % [-1, 11], this picture is for the entire solidworks file
            
            drawingfileimage = imread("SolidworksDrawingImageIconGUI3.png", 'png');
            image(app.UIAxes, 'CData', drawingfileimage, "XData", [-18, -1], "YData", [11, 0]);
        end
        
        function updateText(app)
            line1 = 'Currently on:';
            line2 = sprintf('%s "%s"', app.templateIterator.onFeature.commonTypeName, app.templateIterator.onFeature.name);
            if isempty(app.templateIterator.onTemplateCell.children)
                line3 = 'With No Children';
            else
                line3 = sprintf('With %d children', numel(app.templateIterator.onTemplateCell.children));
            end
            
            line4 = 'Parent:';
            if ~isempty(app.templateIterator.parent)
                line5 = sprintf('%s "%s"', app.templateIterator.parent.onFeature.commonTypeName, app.templateIterator.parent.onFeature.name);
                line6 = sprintf('We are child %d of %d', app.templateIterator.parent.state, numel(app.templateIterator.parent.onTemplateCell.children));
            else
                line5 = 'None';
                line6 = '';
            end
            a = app.ActiveCriterionNode.Children;
            a.delete;
            app.selectedCriterion = 1;
            currentTemplateCell = app.templateIterator.onTemplateCell;
            while app.isValidCriterion()
                currentcriterion = currentTemplateCell.criterions{app.selectedCriterion};
                uitreenode(app.ActiveCriterionNode,'Text', class(currentcriterion))
                app.selectedCriterion = app.selectedCriterion + 1;
            end
            app.Tree2.SelectedNodes = app.ActiveCriterionNode.Children(1);
            app.ActiveCriterionNode.expand;
            %app.PositionText.Value = {line1, line2, line3, line4, line5, line6};
        end
        
        function updatePosition(app)
            % Use the current templateIterator to find out what we are on!
            % Then we zoom onto that feature on the Axis
            
            % Debug
            % disp(app.templateIterator.getStackState);
            
            app.currentSheetHandler = getRelevantSheetHandler(app.templateIterator);
            
            if isempty(app.currentSheetHandler)
                % Go to the drawing file
                app.UIAxes.XLim = [-18, -1];
                app.UIAxes.YLim = [0, 11];
            else
                % Get Location
                [c1, c2] = htemplateLocation(app.templateIterator.onFeature);
                
                % Translate to axis
                [x0, y0] = app.currentSheetHandler.sheetToAxis(c1(1), c1(2));
                [x1, y1] = app.currentSheetHandler.sheetToAxis(c2(1), c2(2));
                
                % Change the preview to that
                app.UIAxes.XLim = [min(x0, x1), max(x0, x1)];
                app.UIAxes.YLim = [min(y0, y1), max(y0, y1)];
            end
            
            app.updateText();
            app.selectedCriterion = 1; % Select the first criterion again
            app.updateValues();        % and update all the value
            function rsheethandler = getRelevantSheetHandler(templateIterator)
                % Figure out what sheet handler this is on.
                rsheethandler = []; % Error
                
                if isa(templateIterator.onFeature, 'comparableSheet')   % This is on a sheet
                    % Return the first sheet handler that has this sheet on
                    % it.
                    
                    
                    for i = 1:numel(app.imageHandlers)
                        if app.imageHandlers(i).sheet == templateIterator.onFeature
                            rsheethandler = app.imageHandlers(i);
                            break;
                        end
                    end
                    
                elseif ~isempty(templateIterator.parent)                % This is on something in a sheet
                    rsheethandler = getRelevantSheetHandler(templateIterator.parent);
                else
                    % We are a drawing file
                end
            end
        end
        
        function updateValues(app)
            % Update everything with respect to criterions!
            
            currentTemplateCell = app.templateIterator.onTemplateCell;
            % Update everything
            app.FeatureWeightEditField.Value = currentTemplateCell.weight;
            app.xEditField.Visible = false;
            app.yEditField.Visible = false;
            app.xminEditField.Visible = false;
            app.yminEditField.Visible = false;
            app.rEditField.Visible = false;
            app.tEditField.Visible = false;
            app.tLabel.Visible = false;
            app.xminLabel.Visible = false;
            app.yminLabel.Visible = false;
            app.xmaxLabel.Visible = false;
            app.ymaxLabel.Visible = false;
            app.rLabel.Visible = false;
            app.displayTolerance(currentTemplateCell)
            if app.isValidCriterion()
                % If its a valid criterion
                currentcriterion = currentTemplateCell.criterions{app.selectedCriterion};
                criterionWeight = currentTemplateCell.criterionweights(app.selectedCriterion);
                criterionDisabled = currentTemplateCell.criteriondisabled(app.selectedCriterion);
                
                % Display Tolerancing
                
                
                app.DisableCriterionCheckBox.Enable = 'on';
                
                app.CriterionWeightEditField.Value = criterionWeight;
                app.DisableCriterionCheckBox.Value = criterionDisabled;
                
                if criterionDisabled % If its disabled turn off editing of the weight
                    app.CriterionWeightEditField.Editable = 'off';
                    app.CriterionWeightEditField.Enable = 'off';
                else
                    app.CriterionWeightEditField.Editable = 'on';
                    app.CriterionWeightEditField.Enable = 'on';
                end
            else
                % Disable Everything
                app.CriterionWeightEditField.Editable = 'off';
                app.CriterionWeightEditField.Enable = 'off';
                app.DisableCriterionCheckBox.Enable = 'off';
            end
            
            % updates the text in CriterionTextArea
            if app.isValidCriterion()
                cr_Text = sprintf('Criterion: %s', class(currentcriterion));
                sc_Text = sprintf('Sum of Criterion Weights: %g', sumCriterionWeights(currentTemplateCell));
                desText = sprintf('Criterion Description: %s', currentcriterion.description);
                tolText = sprintf('Tolerance Tip: %s', currentcriterion.tolerancetip);
            else
                cr_Text = 'Criterion: <NONE>';
                sc_Text = 'Sum of Criterion Weights: 0';
                desText = 'Criterion Description: <NONE>';
                tolText = 'Tolerance Tip: <NONE>';
            end
            fe_Text = sprintf('Feature: %s', currentTemplateCell.onFeature.name);
            sf_Text = sprintf('Sum of Child Weights: %g', sum([currentTemplateCell.children.weight]));
            textCellArray = {fe_Text, cr_Text, '', sf_Text, sc_Text, ''};
            strLen = 0;
            strDis = 0;
            for i = 1:length(currentTemplateCell.criterions)
                newLen = length(class(currentTemplateCell.criterions{i}));
                if newLen > strLen
                    strLen = newLen;
                end
            end
            for i = 1:length(currentTemplateCell.criterions)
                if currentTemplateCell.criteriondisabled(i)
                    strDis = 'disabled';
                else
                    strDis = 'enabled';
                end
                textCellArray{end+1} = sprintf('%-*s    %4g    %s', strLen, class(currentTemplateCell.criterions{i}), currentTemplateCell.criterionweights(i), strDis);
            end
            textCellArray = [textCellArray, {'', desText, tolText}];
            app.CriterionTextArea.Value = textCellArray;
            
            %struct with field rectangles
            %angle with field tolerance
            %circle with field radius
            if isfield(currentcriterion.tolerance, 'rectangles')
                app.xEditField.Visible = true;
                app.yEditField.Visible = true;
                app.xminEditField.Visible = true;
                app.yminEditField.Visible = true;
                app.xminLabel.Visible = true;
                app.yminLabel.Visible = true;
                app.xmaxLabel.Visible = true;
                app.ymaxLabel.Visible = true;
                app.xEditField.Value = currentcriterion.tolerance.rectangles(1);
                app.xminEditField.Value = currentcriterion.tolerance.rectangles(3);
                app.yminEditField.Value = currentcriterion.tolerance.rectangles(4);
                app.yEditField.Value = currentcriterion.tolerance.rectangles(2);
            elseif isfield(currentcriterion.tolerance, 'radius')
                app.rEditField.Visible = true;
                app.rLabel.Visible = true;
                app.rEditField.Value = currentcriterion.tolerance.radius;
            elseif isfield(currentcriterion.tolerance, 'tolerance')
                app.tEditField.Visible = true;
                app.tLabel.Visible = true;
                app.tEditField.Value = currentcriterion.tolerance.tolerance;
            end
            
            function s = sumCriterionWeights(tCell)
                % Sum all non-disabled criterion
                s = sum(tCell.criterionweights(~tCell.criteriondisabled));
            end
        end
        
        function result = isValidCriterion(app)
            result = app.selectedCriterion > 0 && app.selectedCriterion <= numel(app.templateIterator.onTemplateCell.criterions) && ~isempty(app.templateIterator.onTemplateCell.criterions{app.selectedCriterion});
        end
        
        function clickingUI(app)
            if app.isValidCriterion()
                if ~isempty(app.currentSheetHandler) && app.isaltpressed
                    posOfClick = app.UIAxes.CurrentPoint(1,1:2);
                    [trueX, trueY] = app.currentSheetHandler.axisToSheet(posOfClick(1), posOfClick(2));
                    % disp([trueX, trueY]);
                    % Add this point to our points clicked
                    app.pointsClicked = [app.pointsClicked; trueX, trueY];
                end
            end
        end


        % finds the longest name in Tree so the weights can be lined up
        function longest = findLonestTreeTextLength(app)
            longest = 0;
            app.templateIterator = simpleTemplateIterator(app.keyTemplate.roottemplatecell);
            app.updatePosition();
            
            if (longest < length(app.templateIterator.onFeature.name) + strlength(app.templateIterator.onFeature.commonTypeName))
                longest = length(app.templateIterator.onFeature.name) + strlength(app.templateIterator.onFeature.commonTypeName);
            end
            
            for i = 1:length(app.templateIterator.onFeature.childsheets)
                
                if (longest < length(app.templateIterator.onFeature.childsheets(i).name) + strlength(app.templateIterator.onFeature.childsheets(i).commonTypeName))
                    longest = length(app.templateIterator.onFeature.childsheets(i).name) + strlength(app.templateIterator.onFeature.childsheets(i).commonTypeName);
                end
                
            %    if (longest < length(app.templateIterator.onFeature.childsheets(i).childboms(1).name) + strlength(app.templateIterator.onFeature.childsheets(i).childboms(1).commonTypeName))
            %        longest = length(app.templateIterator.onFeature.childsheets(i).childboms(1).name) + strlength(app.templateIterator.onFeature.childsheets(i).childboms(1).commonTypeName);
            %    end
                
                for j = 1:length(app.templateIterator.onFeature.childsheets(i).childviews)
                    if (longest < length(app.templateIterator.onFeature.childsheets(i).childviews(j).name) + strlength(app.templateIterator.onFeature.childsheets(i).childviews(j).commonTypeName))
                        longest = length(app.templateIterator.onFeature.childsheets(i).childviews(j).name) + strlength(app.templateIterator.onFeature.childsheets(i).childviews(j).commonTypeName);
                    end
                end
                
                app.templateIterator = app.templateIterator.nextOver();
            end
        end
        
        % creates a child node of parent for each element in array
        function createChildBOM(app, array, parent, i, nodeName, parentName, longest)
            for k = 1:length(array)
                featureText = "";
                for index = 1:length(app.templateIterator.onTemplateCell.children(i).children)
                    if (strcmp(array(k).name, app.templateIterator.onTemplateCell.children(i).children(index).onFeature.name))
                        featureText = app.templateIterator.onTemplateCell.children(i).children(index).weight;
                        break;
                    end
                end
                %node = uitreenode(parent, 'Text', sprintf('%s Type:%s;    ', array(k).name, array(k).commonTypeName));
                node = uitreenode(parent, 'Text', sprintf('%-*s%s', longest, sprintf('%s Type:%s;', array(k).name, array(k).commonTypeName), string(featureText)));
                    
                if (strcmp(array(k).name, nodeName) && strcmp(array(k).parent.name, parentName))
                    app.Tree.SelectedNodes = node;
                end
            end
        end
        
        % creates a child node of parent for each element in array
        function createChild(app, array, parent, i, j, nodeName, parentName, longest)
            for k = 1:length(array)
                featureText = "";
                for index = 1:length(app.templateIterator.onTemplateCell.children(i).children(j).children)
                    if (strcmp(array(k).name, app.templateIterator.onTemplateCell.children(i).children(j).children(index).onFeature.name))
                        featureText = app.templateIterator.onTemplateCell.children(i).children(j).children(index).weight;
                        break;
                    end
                end
                %node = uitreenode(parent, 'Text', sprintf('%s Type:%s;    %s', array(k).name, array(k).commonTypeName, string(featureText)));
                node = uitreenode(parent, 'Text', sprintf('%-*s%s', longest, sprintf('%s Type:%s;', array(k).name, array(k).commonTypeName), string(featureText)));
                    
                if (strcmp(array(k).name, nodeName) && strcmp(array(k).parent.name, parentName))
                    app.Tree.SelectedNodes = node;
                end
            end
        end
        
        % populates the tree with nodes
        function createTree(app, nodeName, parentName)
            longest = app.findLonestTreeTextLength();
            longest = longest + 8;
            app.templateIterator = simpleTemplateIterator(app.keyTemplate.roottemplatecell);
            app.updatePosition();
            %file = uitreenode(app.Tree, 'Text', sprintf('%s Type:%s;    %s', app.templateIterator.onFeature.name, app.templateIterator.onFeature.commonTypeName, string(app.templateIterator.onTemplateCell.weight)));
            file = uitreenode(app.Tree, 'Text', sprintf('%-*s%s', longest, sprintf('%s Type:%s;', app.templateIterator.onFeature.name, app.templateIterator.onFeature.commonTypeName), string(app.templateIterator.onTemplateCell.weight)));
            
            for i = 1:length(app.templateIterator.onFeature.childsheets)
                %sheet = uitreenode(file, 'Text', sprintf('%s Type:%s;    %s', app.templateIterator.onFeature.childsheets(i).name, app.templateIterator.onFeature.childsheets(i).commonTypeName, string(app.templateIterator.onTemplateCell.children(i).weight)));
                sheet = uitreenode(file, 'Text', sprintf('%-*s%s', longest, sprintf('%s Type:%s;', app.templateIterator.onFeature.childsheets(i).name, app.templateIterator.onFeature.childsheets(i).commonTypeName), string(app.templateIterator.onTemplateCell.children(i).weight)));
                
                if (strcmp(app.templateIterator.onFeature.childsheets(i).name, nodeName) && strcmp(app.templateIterator.onFeature.name, parentName))
                    app.Tree.SelectedNodes = sheet;
                end
                
                app.createChildBOM(app.templateIterator.onFeature.childsheets(i).childboms, sheet, i, nodeName, parentName, longest);
                
                for j = 1:length(app.templateIterator.onFeature.childsheets(i).childviews)
                    %view = uitreenode(sheet, 'Text', sprintf('%s Type:%s;    %s', app.templateIterator.onFeature.childsheets(i).childviews(j).name, app.templateIterator.onFeature.childsheets(i).childviews(j).commonTypeName, string(app.templateIterator.onTemplateCell.children(i).children(j).weight)));
                    view = uitreenode(sheet, 'Text', sprintf('%-*s%s', longest, sprintf('%s Type:%s;', app.templateIterator.onFeature.childsheets(i).childviews(j).name, app.templateIterator.onFeature.childsheets(i).childviews(j).commonTypeName), string(app.templateIterator.onTemplateCell.children(i).children(j).weight)));
                    
                    if (strcmp(app.templateIterator.onFeature.childsheets(i).childviews(j).name, nodeName) && strcmp(app.templateIterator.onFeature.childsheets(i).name, parentName))
                        app.Tree.SelectedNodes = view;
                    end
                    
                    app.createChild(app.templateIterator.onFeature.childsheets(i).childviews(j).childcenterlines, view, i, j, nodeName, parentName, longest);
                    app.createChild(app.templateIterator.onFeature.childsheets(i).childviews(j).childballoons, view, i, j, nodeName, parentName, longest);
                    app.createChild(app.templateIterator.onFeature.childsheets(i).childviews(j).childdatums, view, i, j, nodeName, parentName, longest);
                    app.createChild(app.templateIterator.onFeature.childsheets(i).childviews(j).childdims, view, i, j, nodeName, parentName, longest);
                    app.createChild(app.templateIterator.onFeature.childsheets(i).childviews(j).childcentermarks, view, i, j, nodeName, parentName, longest);
                end
                
                app.templateIterator = app.templateIterator.nextOver();
            end
            
            if isempty(app.Tree.SelectedNodes)
                app.Tree.SelectedNodes = file;
            else
                app.TreeSelectionChanged();
            end
        end


        % get where the user has selected in the heirarchy
        function results = getLevel(app, parentType)
            switch parentType
                case 'Tree'
                    results = 1;
                case 'Drawing'
                    results = 2;
                case 'Sheet'
                    results = 3;
                case 'View'
                    results = 4;
            end
        end
        
        % the inputs indicate how far down the tree to move
        function iterLevel = moveIterDown(app, iterLevel, selectLevel)
            while ((selectLevel -iterLevel) > 1)
                if selectLevel - iterLevel == 3
                     nodeParentTypeIndArr = strfind(app.Tree.SelectedNodes.Parent.Parent.Text, ':');
                     nodeParentTypeInd = nodeParentTypeIndArr(end);
                     nodeParentName = app.Tree.SelectedNodes.Parent.Parent.Text(1:nodeParentTypeInd-6);
                     nodeGrandParentTypeIndArr = strfind(app.Tree.SelectedNodes.Parent.Parent.Parent.Text, ':');
                     nodeGrandParentTypeInd = nodeGrandParentTypeIndArr(end);
                     nodeGrandParentName =  app.Tree.SelectedNodes.Parent.Parent.Parent.Text(1:nodeGrandParentTypeInd-6);
                else
                    nodeParentTypeIndArr = strfind(app.Tree.SelectedNodes.Parent.Text, ':');
                    nodeParentTypeInd = nodeParentTypeIndArr(end);
                    nodeParentName = app.Tree.SelectedNodes.Parent.Text(1:nodeParentTypeInd-6);
                    nodeGrandParentTypeIndArr = strfind(app.Tree.SelectedNodes.Parent.Parent.Text, ':');
                    nodeGrandParentTypeInd = nodeGrandParentTypeIndArr(end);
                    nodeGrandParentName =  app.Tree.SelectedNodes.Parent.Parent.Text(1:nodeGrandParentTypeInd-6);
                end
                % keep moving the iterator until it matches the grandparent
                % of what the new selection is
                while(~strcmp(nodeGrandParentName, app.templateIterator.onFeature.name))
                    app.templateIterator = app.templateIterator.nextOver();
                end
                % move the iterator down
                app.templateIterator = app.templateIterator.next();
                % same thing as before but with parent
                while(~strcmp(nodeParentName, app.templateIterator.onFeature.name))
                    app.templateIterator = app.templateIterator.nextOver();
                end
                % update level to break out of loop
                iterLevel = app.getLevel(app.templateIterator.parent.onFeature.commonTypeName);
            end
            % go down one more now we are at the correct level
            app.templateIterator = app.templateIterator.next();
            iterLevel = app.getLevel(app.templateIterator.parent.onFeature.commonTypeName);
        end
        
        function displayTolerance(app, currentTemplateCell)
            % display all graphics for an object when it is selected
            for i = 1:length(currentTemplateCell.criterions)
                currentcriterion = currentTemplateCell.criterions{i};
                if ~isempty(currentcriterion)
                    app.currentGraphics = [app.currentGraphics, currentcriterion.showTolerance(currentTemplateCell.onFeature, app.currentSheetHandler, app.criterionColours)];
                end
           end
        end
        
        function deleteGraphics(app, currentTemplateCell)
            delete(app.currentGraphics);
        end


        % deletes the old tree and creates a new one
        function updateTree(app)
            nodeTypeIndArr = strfind(app.Tree.SelectedNodes.Text, ':');
            nodeTypeInd = nodeTypeIndArr(end);
            if length(nodeTypeInd) > 1
                nodeName = app.Tree.SelectedNodes.Text(1:nodeTypeInd(end)-6);
            else
                nodeName = app.Tree.SelectedNodes.Text(1:nodeTypeInd-6);
            end
            
            if (strcmp(app.Tree.SelectedNodes.Parent.Type, 'uitree'))
                parentName = 'N/A';
            else
                parentTypeIntArr = strfind(app.Tree.SelectedNodes.Parent.Text, ':');
                parentTypeInt = parentTypeIntArr(end);
                if length(nodeTypeInd) > 1
                    parentName = app.Tree.SelectedNodes.Parent.Text(1:parentTypeInt(end)-6);
                else
                    parentName = app.Tree.SelectedNodes.Parent.Text(1:parentTypeInt-6);
                end
            end
            
            app.Tree.Children.delete;
            app.createTree(nodeName, parentName);
            expand(app.Tree, 'all');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, startingapp, keyTemplate, studentFolder)
            app.UIFigure.Name = 'Modifying Template';
            startingapp.logOutput('Launching Template App')
            app.studentFolder = studentFolder;  % location used when saving template file
            app.xEditField.Visible = false;
            app.yEditField.Visible = false;
            app.xminEditField.Visible = false;
            app.yminEditField.Visible = false;
            app.rEditField.Visible = false;
            app.tEditField.Visible = false;
            app.tLabel.Visible = false;
            app.xminLabel.Visible = false;
            app.yminLabel.Visible = false;
            app.xmaxLabel.Visible = false;
            app.ymaxLabel.Visible = false;
            app.rLabel.Visible = false;
            app.keyTemplate = keyTemplate;              % Key Template is important
            app.setupImages();                          % Run initial image setups
            app.keyTemplate.editDate = datetime();      % New Date for Modification
            app.createTree('N/A', 'N/A');
            expand(app.Tree, 'all');
        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            % Reduces how far off we are from 11/17.
            
            currentXlimits = app.UIAxes.XLim;
            currentYlimits = app.UIAxes.YLim;
            
            XAv = sum(currentXlimits)/2;
            YAv = sum(currentYlimits)/2;
            
            width = abs(currentXlimits(2) - currentXlimits(1));
            height = abs(currentYlimits(2) - currentYlimits(1));
            
            if 17*height > 11*width
                % Too tall
                correctWidth = 17/11*height;
                changeRatio = (3* correctWidth + width)/(4*width);
                app.UIAxes.XLim = (currentXlimits - XAv)*changeRatio + XAv;
            else
                % Too Wide
                correctHeight = 11/17*width;
                changeRatio = (3* correctHeight + height)/(4*height);
                app.UIAxes.YLim = (currentYlimits - YAv)*changeRatio + YAv;
            end
        end

        % Value changed function: FeatureWeightEditField
        function FeatureWeightEditFieldValueChanged(app, event)
            value = app.FeatureWeightEditField.Value;
            currentTemplateCell = app.templateIterator.onTemplateCell;
            currentTemplateCell.weight = value;
            app.updateValues();
            
            % Updates the feature weights of the parents.
            % Assumes jumpOut returns the same object when there are no parents
            %*********************************************************
            parentThing = app.templateIterator;
            for level = 1:3
                newParentThing = parentThing.jumpOut();
                if (strcmp(parentThing.onFeature.name, newParentThing.onFeature.name))
                    break;
                end
                parentThing = newParentThing;
                
                sumOfWeights = 0;
                len1 = length(parentThing.onTemplateCell.children);
                for i = 1:len1
                    sumOfWeights = sumOfWeights + parentThing.onTemplateCell.children(i).weight;
                end
                %len2 = length(parentThing.onTemplateCell.criterionweights);
                %for i = 1:len2
                %    if parentThing.onTemplateCell.criteriondisabled(i) == 0
                %        sumOfWeights = sumOfWeights + parentThing.onTemplateCell.criterionweights(i);
                %    end
                %end
                parentThing.onTemplateCell.weight = sumOfWeights;
                app.updateValues();
            end
            %*********************************************************
            
            app.updateTree();
        end

        % Value changed function: DisableCriterionCheckBox
        function DisableCriterionCheckBoxValueChanged(app, event)
            value = app.DisableCriterionCheckBox.Value;
            currentTemplateCell = app.templateIterator.onTemplateCell;
            if app.isValidCriterion()
                currentTemplateCell.criteriondisabled(app.selectedCriterion) = value;
            end
            app.updateValues();
            
            %{
            % Updates the feature weights of the parents.
            % Assumes jumpOut returns the same object when there are no parents
            %*********************************************************
            parentThing = app.templateIterator;
            for level = 1:4
                sumOfWeights = 0;
                len1 = length(parentThing.onTemplateCell.children);
                for i = 1:len1
                    sumOfWeights = sumOfWeights + parentThing.onTemplateCell.children(i).weight;
                end
                len2 = length(parentThing.onTemplateCell.criterionweights);
                for i = 1:len2
                    if parentThing.onTemplateCell.criteriondisabled(i) == 0
                        sumOfWeights = sumOfWeights + parentThing.onTemplateCell.criterionweights(i);
                    end
                end
                parentThing.onTemplateCell.weight = sumOfWeights;
                app.updateValues();
                
                newParentThing = parentThing.jumpOut();
                if (strcmp(parentThing.onFeature.name, newParentThing.onFeature.name))
                    break;
                end
                parentThing = newParentThing;
            end
            %*********************************************************
            
            app.updateTree();
            %}
        end

        % Value changed function: CriterionWeightEditField
        function CriterionWeightEditFieldValueChanged(app, event)
            value = app.CriterionWeightEditField.Value;
            currentTemplateCell = app.templateIterator.onTemplateCell;
            if ~app.DisableCriterionCheckBox.Value
                if app.isValidCriterion()
                    currentTemplateCell.criterionweights(app.selectedCriterion) = value;
                end
            end
            app.updateValues();
            
            %{
            % Updates the feature weights of the parents.
            % Assumes jumpOut returns the same object when there are no parents
            %*********************************************************
            parentThing = app.templateIterator;
            for level = 1:4
                sumOfWeights = 0;
                len1 = length(parentThing.onTemplateCell.children);
                for i = 1:len1
                    sumOfWeights = sumOfWeights + parentThing.onTemplateCell.children(i).weight;
                end
                len2 = length(parentThing.onTemplateCell.criterionweights);
                for i = 1:len2
                    if parentThing.onTemplateCell.criteriondisabled(i) == 0
                        sumOfWeights = sumOfWeights + parentThing.onTemplateCell.criterionweights(i);
                    end
                end
                parentThing.onTemplateCell.weight = sumOfWeights;
                app.updateValues();
                
                newParentThing = parentThing.jumpOut();
                if (strcmp(parentThing.onFeature.name, newParentThing.onFeature.name))
                    break;
                end
                parentThing = newParentThing;
            end
            %*********************************************************
            
            app.updateTree();
            %}
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            crashPreventer = 0;
            
            % basically this function gets the iterator to where the
            % user has selected and updates all the graphics
            app.deleteGraphics(app.templateIterator.onTemplateCell);
            nodeTypeIndArr = strfind(app.Tree.SelectedNodes.Text, ':');
            nodeTypeInd = nodeTypeIndArr(end);
            if length(nodeTypeInd) > 1
                nodeName = app.Tree.SelectedNodes.Text(1:nodeTypeInd(end)-6);
            else
                nodeName = app.Tree.SelectedNodes.Text(1:nodeTypeInd-6);
            end
            
            safe = false;
            nodeParentName = '';
            %get data about where we want to go
            if strcmp(class(app.Tree.SelectedNodes.Parent), 'matlab.ui.container.Tree')
                nodeParentType = 'Tree';
            else
                nodeParentTypeIndArr = strfind(app.Tree.SelectedNodes.Parent.Text, ':');
                nodeParentTypeInd = nodeParentTypeIndArr(end);
                nodeParentTypeLST = strfind(app.Tree.SelectedNodes.Parent.Text, ';');
                
                nodeParentType = app.Tree.SelectedNodes.Parent.Text((nodeParentTypeInd+1):(nodeParentTypeLST-1));
                nodeParentName = app.Tree.SelectedNodes.Parent.Text(1:nodeParentTypeInd-6);
            end
         
            selectLevel = app.getLevel(nodeParentType);
            % get data about where we are
            if isempty(app.templateIterator.parent)
                iterLevel = 1;
            else
                iterLevel = app.getLevel(app.templateIterator.parent.onFeature.commonTypeName);
            end
            % need to go up not too hard
            while iterLevel > selectLevel
                app.templateIterator = app.templateIterator.jumpOut();
                if isempty(app.templateIterator.parent)
                    iterLevel = 1;
                else
                    iterLevel = app.getLevel(app.templateIterator.parent.onFeature.commonTypeName);
                end
                try
                    if isempty(nodeParentName) && isempty(app.templateIterator.parent)
                        safe = true;
                    elseif(strcmp(nodeParentName, app.templateIterator.parent.onFeature.name))
                        safe = true;
                    end
                catch
                end
            end
            % need to go down and through the right path
            while iterLevel < selectLevel
                if(selectLevel - iterLevel) > 1
                    safe = true;
                end
                iterLevel = app.moveIterDown(iterLevel, selectLevel);
                
            end
            % need to go up and around and back out ensures success
            if ~safe
                while ~(iterLevel == 1)
                    app.templateIterator = app.templateIterator.jumpOut();
                    if isempty(app.templateIterator.parent)
                        iterLevel = 1;
                    else
                        iterLevel = app.getLevel(app.templateIterator.parent.onFeature.commonTypeName);
                    end
                end
                app.moveIterDown(iterLevel, selectLevel);
            end
            while(~strcmp(nodeName, app.templateIterator.onFeature.name) && crashPreventer<1000)
                app.templateIterator = app.templateIterator.nextOver();
                crashPreventer = crashPreventer + 1;
            end
            app.updatePosition();
        end

        % Selection changed function: Tree2
        function Tree2SelectionChanged(app, event)
            selectedNodes = app.Tree2.SelectedNodes;
            for i = 1:length(app.ActiveCriterionNode.Children)
                if app.ActiveCriterionNode.Children(i) == selectedNodes
                    app.selectedCriterion = i;
                end
            end
            app.updateValues();
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            currentTemplateCell = app.templateIterator.onTemplateCell;
            currentcriterion = currentTemplateCell.criterions{app.selectedCriterion};
            currentcriterion.tolerance.rectangles(1) = (value);
            delete(app.currentGraphics);
            app.currentGraphics = currentcriterion.showTolerance(currentTemplateCell.onFeature, app.currentSheetHandler, app.criterionColours);
            app.DisableCriterionCheckBox.Enable = 'on';
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            value = app.yEditField.Value;
            currentTemplateCell = app.templateIterator.onTemplateCell;
            currentcriterion = currentTemplateCell.criterions{app.selectedCriterion};
            currentcriterion.tolerance.rectangles(2) = (value);
            delete(app.currentGraphics);
            app.currentGraphics = currentcriterion.showTolerance(currentTemplateCell.onFeature, app.currentSheetHandler, app.criterionColours);
            app.DisableCriterionCheckBox.Enable = 'on';
        end

        % Value changed function: rEditField
        function rEditFieldValueChanged(app, event)
            value = app.rEditField.Value;
            currentTemplateCell = app.templateIterator.onTemplateCell;
            currentcriterion = currentTemplateCell.criterions{app.selectedCriterion};
            currentcriterion.tolerance.radius = value;
            delete(app.currentGraphics);
            app.displayTolerance(currentTemplateCell);
            app.DisableCriterionCheckBox.Enable = 'on';
        end

        % Value changed function: tEditField
        function tEditFieldValueChanged(app, event)
            value = app.tEditField.Value;
            currentTemplateCell = app.templateIterator.onTemplateCell;
            currentcriterion = currentTemplateCell.criterions{app.selectedCriterion};
            currentcriterion.tolerance.tolerance = value;
            delete(app.currentGraphics);
            app.displayTolerance(currentTemplateCell);
            app.DisableCriterionCheckBox.Enable = 'on';
        end

        % Value changed function: xminEditField
        function xminEditFieldValueChanged(app, event)
            value = app.xminEditField.Value;
            currentTemplateCell = app.templateIterator.onTemplateCell;
            currentcriterion = currentTemplateCell.criterions{app.selectedCriterion};
            currentcriterion.tolerance.rectangles(3) = (value);
            delete(app.currentGraphics);
            app.displayTolerance(currentTemplateCell);
            app.DisableCriterionCheckBox.Enable = 'on';
        end

        % Value changed function: yminEditField
        function yminEditFieldValueChanged(app, event)
            value = app.yminEditField.Value;
            currentTemplateCell = app.templateIterator.onTemplateCell;
            currentcriterion = currentTemplateCell.criterions{app.selectedCriterion};
            currentcriterion.tolerance.rectangles(4) = (value);
            delete(app.currentGraphics);
            app.displayTolerance(currentTemplateCell);
            app.DisableCriterionCheckBox.Enable = 'on';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.keyTemplate)
                return;
            end

            % Set GUI to go into directory one level above student folder
            % If no student folder, load into working directory
            oneFolderAboveStud = '';
            if ~strcmp(app.studentFolder, '')
                backSlashLocations = strfind(app.studentFolder, '\');
                lastBackSlash = backSlashLocations(end);
                oneFolderAboveStud = app.studentFolder(1:lastBackSlash);
            end
            
            [templatefile, templatepath] = uiputfile('.mat', "Save Template As", [oneFolderAboveStud, 'keytemplate.mat']);
            
            if isequal(templatefile, 0) || isequal(templatepath, 0)
                return;
            end
            templatefullpath = fullfile(templatepath, templatefile);
            
            drawingkeyTemplate = app.keyTemplate;
            % Save it
            save(templatefullpath, 'drawingkeyTemplate');
            figure(app.UIFigure);
        end

        % Button pushed function: CreateAssignmentSummaryButton
        function CreateAssignmentSummaryButtonPushed(app, event)
            buildTextForMarkingGuide(app.keyTemplate);
        end

        % Button pushed function: UpdateTreeButton
        function UpdateTreeButtonPushed(app, event)
            app.updateTree();
        end

        % Button pushed function: ColDrawingsButton
        function ColDrawingsButtonPushed(app, event)
            for sheet = 1:length(app.Tree.Children.Children)
                for drawing = 1:length(app.Tree.Children.Children(sheet).Children)
                    collapse(app.Tree.Children.Children(sheet).Children(drawing));
                end
            end
        end

        % Button pushed function: ColSheetsButton
        function ColSheetsButtonPushed(app, event)
            for sheet = 1:length(app.Tree.Children.Children)
                collapse(app.Tree.Children.Children(sheet));
            end
        end

        % Button pushed function: ExpDrawingsButton
        function ExpDrawingsButtonPushed(app, event)
            for sheet = 1:length(app.Tree.Children.Children)
                for drawing = 1:length(app.Tree.Children.Children(sheet).Children)
                    expand(app.Tree.Children.Children(sheet).Children(drawing));
                end
            end
        end

        % Button pushed function: ExpSheetsButton
        function ExpSheetsButtonPushed(app, event)
            for sheet = 1:length(app.Tree.Children.Children)
                expand(app.Tree.Children.Children(sheet));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [-18 -1];
            app.UIAxes.YLim = [0 11];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ScaleButton = uibutton(app.UIFigure, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Tooltip = {'S'; 'Moves towards an 11x17 ratio.'};
            app.ScaleButton.Text = 'Scale';
            app.DisableCriterionCheckBox = uicheckbox(app.UIFigure);
            app.DisableCriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisableCriterionCheckBoxValueChanged, true);
            app.DisableCriterionCheckBox.Tooltip = {'Ctrl + Space'};
            app.DisableCriterionCheckBox.Text = 'Disable Criterion';
            app.CriterionWeightEditFieldLabel = uilabel(app.UIFigure);
            app.CriterionWeightEditFieldLabel.Text = 'Criterion Weight';
            app.CriterionWeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.CriterionWeightEditField.ValueChangedFcn = createCallbackFcn(app, @CriterionWeightEditFieldValueChanged, true);
            app.CriterionWeightEditField.Tooltip = {'Ctrl + Up/Down Arrow'};
            app.CriterionTextArea = uitextarea(app.UIFigure);
            app.CriterionTextArea.Editable = 'off';
            app.CriterionTextArea.Value = {'Criterion Selected <d/d> : <s>'; 'Current Sum of Criterion weights is <g>'; 'Sum of Child Feature weights is <g> , Parent Weight is <g>'; 'Description: <s>'; 'Tolerance Tip: <s>'};
            app.FeatureWeightEditFieldLabel = uilabel(app.UIFigure);
            app.FeatureWeightEditFieldLabel.Text = 'Feature Weight';
            app.FeatureWeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.FeatureWeightEditField.Limits = [0 Inf];
            app.FeatureWeightEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureWeightEditFieldValueChanged, true);
            app.FeatureWeightEditField.Tooltip = {'Shift + Ctrl + Up/Down Arrows'};
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree2 = uitree(app.UIFigure);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2SelectionChanged, true);
            app.ActiveCriterionNode = uitreenode(app.Tree2);
            app.ActiveCriterionNode.Text = 'Active Criterion';
            app.HTML = uihtml(app.UIFigure);
            app.HTML.Visible = 'off';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.rEditField = uieditfield(app.UIFigure, 'numeric');
            app.rEditField.ValueChangedFcn = createCallbackFcn(app, @rEditFieldValueChanged, true);
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.tEditField.ValueChangedFcn = createCallbackFcn(app, @tEditFieldValueChanged, true);
            app.tLabel = uilabel(app.UIFigure);
            app.tLabel.Text = 't';
            app.rLabel = uilabel(app.UIFigure);
            app.rLabel.Text = 'r';
            app.xminLabel = uilabel(app.UIFigure);
            app.xminLabel.Text = 'xmin';
            app.yminLabel = uilabel(app.UIFigure);
            app.yminLabel.Text = 'ymin';
            app.xminEditField = uieditfield(app.UIFigure, 'numeric');
            app.xminEditField.ValueChangedFcn = createCallbackFcn(app, @xminEditFieldValueChanged, true);
            app.xmaxLabel = uilabel(app.UIFigure);
            app.xmaxLabel.Text = 'xmax';
            app.ymaxLabel = uilabel(app.UIFigure);
            app.ymaxLabel.Text = 'ymax';
            app.yminEditField = uieditfield(app.UIFigure, 'numeric');
            app.yminEditField.ValueChangedFcn = createCallbackFcn(app, @yminEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CreateAssignmentSummaryButton = uibutton(app.UIFigure, 'push');
            app.CreateAssignmentSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAssignmentSummaryButtonPushed, true);
            app.CreateAssignmentSummaryButton.Text = {'Create'; 'Assignment'; 'Summary'};
            app.UpdateTreeButton = uibutton(app.UIFigure, 'push');
            app.UpdateTreeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTreeButtonPushed, true);
            app.UpdateTreeButton.Text = 'Update Tree';
            app.ColDrawingsButton = uibutton(app.UIFigure, 'push');
            app.ColDrawingsButton.ButtonPushedFcn = createCallbackFcn(app, @ColDrawingsButtonPushed, true);
            app.ColDrawingsButton.Text = 'Col. Drawings';
            app.ExpDrawingsButton = uibutton(app.UIFigure, 'push');
            app.ExpDrawingsButton.ButtonPushedFcn = createCallbackFcn(app, @ExpDrawingsButtonPushed, true);
            app.ExpDrawingsButton.Text = 'Exp. Drawings';
            app.ExpSheetsButton = uibutton(app.UIFigure, 'push');
            app.ExpSheetsButton.ButtonPushedFcn = createCallbackFcn(app, @ExpSheetsButtonPushed, true);
            app.ExpSheetsButton.Text = 'Exp. Sheets';
            app.ColSheetsButton = uibutton(app.UIFigure, 'push');
            app.ColSheetsButton.ButtonPushedFcn = createCallbackFcn(app, @ColSheetsButtonPushed, true);
            app.ColSheetsButton.Text = 'Col. Sheets';
            app.Button1 = uibutton(app.UIFigure, 'push');
            app.Button1.Text = 'Button1';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MarkingTemplateGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end