classdef SettingsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ScoreTablePositionListBox       matlab.ui.control.ListBox
        ScoreTablePositionListBoxLabel  matlab.ui.control.Label
        DatumEditField                  matlab.ui.control.NumericEditField
        DatumEditFieldLabel             matlab.ui.control.Label
        DimensionEditField              matlab.ui.control.NumericEditField
        DimensionEditFieldLabel         matlab.ui.control.Label
        BalloonEditField                matlab.ui.control.NumericEditField
        BalloonEditFieldLabel           matlab.ui.control.Label
        CenterMarkEditField             matlab.ui.control.NumericEditField
        CenterMarkEditFieldLabel        matlab.ui.control.Label
        CenterLineEditField             matlab.ui.control.NumericEditField
        CenterLineLabel                 matlab.ui.control.Label
        BillofMaterialsEditField        matlab.ui.control.NumericEditField
        BillofMaterialsLabel            matlab.ui.control.Label
        DefaultFeatureWeightsLabel      matlab.ui.control.Label
        DefaultRoundingListBox          matlab.ui.control.ListBox
        DefaultRoundingLabel            matlab.ui.control.Label
        PositiveFeedbackForListBox      matlab.ui.control.ListBox
        PositiveFeedbackForLabel        matlab.ui.control.Label
        CorrectionSettingsLabel         matlab.ui.control.Label
        Button_6                        matlab.ui.control.Button
        CorrectLabel                    matlab.ui.control.Label
        AssignmentTotalEditField        matlab.ui.control.NumericEditField
        AssignmentTotalEditFieldLabel   matlab.ui.control.Label
        FontSizeEditField               matlab.ui.control.NumericEditField
        FontSizeEditFieldLabel          matlab.ui.control.Label
        FontNameTextArea                matlab.ui.control.TextArea
        FontNameTextAreaLabel           matlab.ui.control.Label
        EmailTextArea                   matlab.ui.control.TextArea
        EmailTextAreaLabel              matlab.ui.control.Label
        CurrentSemesterTextArea         matlab.ui.control.TextArea
        CurrentSemesterLabel_2          matlab.ui.control.Label
        CourseNameTextArea              matlab.ui.control.TextArea
        CourseNameLabel                 matlab.ui.control.Label
        AssignmentNoTextArea            matlab.ui.control.TextArea
        AssignmentNoTextAreaLabel       matlab.ui.control.Label
        ProfessorNameTextArea           matlab.ui.control.TextArea
        ProfessorNameLabel              matlab.ui.control.Label
        Button_5                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        MiscLabel                       matlab.ui.control.Label
        PositionLabel                   matlab.ui.control.Label
        ValueLabel                      matlab.ui.control.Label
        UnrecognizedLabel               matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        MissingLabel                    matlab.ui.control.Label
        CorrectionColoursLabel          matlab.ui.control.Label
        ReportSettingsLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        settings = {}; % Description
        colours;
        fontName = '';
        fontSize;
        validFonts = listfonts();
        weights = {};
    end
    
    
    methods (Access = private)
        
        function writeSettings(app, settingsCell)
            % get path to settings file
            currentFolder = fileparts(which(mfilename));
            ind = strfind(currentFolder, '\');
            newFolder = currentFolder(1:ind(end-1)-1);
            newFolder = fullfile(newFolder, 'REPORTTOOLS', 'LATEXPRINTING');
            settingsFile = fullfile(newFolder, 'DefaultReportSettings.txt');
            fid = fopen(settingsFile, 'w');
            for i = 1:length(settingsCell)
                % print a line with each setting
                fprintf(fid, settingsCell{i});
                fprintf(fid, '\n');
            end
            fclose(fid);
        end
        % update each time something changes
        function settingChanged(app, index, value)
            app.settings(index) = value;
            app.writeSettings(app.settings);
        end


        function updateWeights(app)
            currentFolder = fileparts(which(mfilename));
            ind = strfind(currentFolder, '\');
            newFolder = currentFolder(1:ind(end-1)-1);
            newFolder = fullfile(newFolder,'AUTOMARK','MarkingCriterion','BaseCriterion');
            settingsFile = fullfile(newFolder, 'DefaultWeights.txt');
            fid = fopen(settingsFile, 'w');
            for i = 1:length(app.weights)
                % print a line with each setting
                fprintf(fid, app.weights{i});
                fprintf(fid, '\n');
            end
            fclose(fid);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %getSettingsArray and getDefaultWeights are in AUTOMARK folder
            [app.settings] = getSettingsArray();
            % go to criterionColoursClass.m
            app.colours = criterionColoursClass();
            [app.weights] = getDefaultWeights();
            app.ProfessorNameTextArea.Value = app.settings{1};
            app.AssignmentNoTextArea.Value = app.settings{2};
            app.CourseNameTextArea.Value = app.settings{3};
            app.CurrentSemesterTextArea.Value = app.settings{4};
            app.EmailTextArea.Value = app.settings{5};
            app.AssignmentTotalEditField.Value = str2double(app.settings{6});
            app.DefaultRoundingListBox.Value = app.DefaultRoundingListBox.Items{str2double(app.settings{7})};
            app.FontNameTextArea.Value = app.colours.fontName;
            app.FontSizeEditField.Value = app.colours.fontSize;
            app.Button.BackgroundColor = app.colours.missing./255;
            app.Button_2.BackgroundColor = app.colours.unrecognized./255;
            app.Button_3.BackgroundColor = app.colours.value./255;
            app.Button_4.BackgroundColor = app.colours.position./255;
            app.Button_5.BackgroundColor = app.colours.misc./255;
            app.Button_6.BackgroundColor = app.colours.correct./255;
            app.PositiveFeedbackForListBox.Value = app.PositiveFeedbackForListBox.Items{app.colours.feedbackSetting};
            app.ScoreTablePositionListBox.Value = app.ScoreTablePositionListBox.Items{app.colours.tablePosition};
            app.BillofMaterialsEditField.Value = str2double(app.weights{6});
            app.CenterLineEditField.Value = str2double(app.weights{2});
            app.CenterMarkEditField.Value = str2double(app.weights{3});
            app.DimensionEditField.Value = str2double(app.weights{1});
            app.BalloonEditField.Value = str2double(app.weights{5});
            app.DatumEditField.Value = str2double(app.weights{4});
        end

        % Value changed function: ProfessorNameTextArea
        function ProfessorNameTextAreaValueChanged(app, event)
            value = app.ProfessorNameTextArea.Value;
            app.settingChanged(1, value);
        end

        % Value changed function: AssignmentNoTextArea
        function AssignmentNoTextAreaValueChanged(app, event)
            value = app.AssignmentNoTextArea.Value;
            app.settingChanged(2, value);
        end

        % Value changed function: CourseNameTextArea
        function CourseNameTextAreaValueChanged(app, event)
            value = app.CourseNameTextArea.Value;
            app.settingChanged(3, value);
        end

        % Value changed function: CurrentSemesterTextArea
        function CurrentSemesterTextAreaValueChanged(app, event)
            value = app.CurrentSemesterTextArea.Value;
            app.settingChanged(4, value);
        end

        % Value changed function: EmailTextArea
        function EmailTextAreaValueChanged(app, event)
            value = app.EmailTextArea.Value;
            app.settingChanged(5, value);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.colours.missing = round(uisetcolor()*255);
            app.Button.BackgroundColor = app.colours.missing./255;
            app.colours.updateSettings();
            figure(app.UIFigure);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            userColour = (uisetcolor());
            if(numel(userColour) == 3)
                app.colours.unrecognized = round(userColour*255);
                app.Button_2.BackgroundColor = app.colours.unrecognized./255;
                app.colours.updateSettings();
                figure(app.UIFigure);
            else
                return;
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            userColour = (uisetcolor());
            if(numel(userColour) == 3)
                app.colours.value = round(userColour*255);
                app.Button_3 .BackgroundColor= app.colours.value ./ 255;
                app.colours.updateSettings();
                figure(app.UIFigure);
            else
                return 
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            userColour = (uisetcolor());
            if(numel(userColour) == 3)
                app.colours.position = round(userColour*255);
                app.Button_4.BackgroundColor = app.colours.position./255;
                app.colours.updateSettings();
                figure(app.UIFigure);
            else
                return
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            userColour = (uisetcolor());
            if(numel(userColour) == 3)
                app.colours.misc = round(userColour*255);
                app.Button_5.BackgroundColor = app.colours.misc./255;
                app.colours.updateSettings();
                figure(app.UIFigure);
            else
                return
            end
        end

        % Value changed function: FontNameTextArea
        function FontNameTextAreaValueChanged(app, event)
            value = app.FontNameTextArea.Value{1};
            app.colours.fontName = value;
            app.colours.updateSettings();
        end

        % Value changed function: FontSizeEditField
        function FontSizeEditFieldValueChanged(app, event)
            value = app.FontSizeEditField.Value;
            app.colours.fontSize = value;
            app.colours.updateSettings();
        end

        % Value changed function: AssignmentTotalEditField
        function AssignmentTotalEditFieldValueChanged(app, event)
            value = {int2str(app.AssignmentTotalEditField.Value)};
            app.settingChanged(6, value);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            userColour = (uisetcolor());
            if(numel(userColour) == 3)
                app.colours.correct = round(userColour*255);
                app.Button_6.BackgroundColor = app.colours.correct./255;
                app.colours.updateSettings();
                figure(app.UIFigure);
            else
                return
            end
        end

        % Value changed function: PositiveFeedbackForListBox
        function PositiveFeedbackForListBoxValueChanged(app, event)
            value = app.PositiveFeedbackForListBox.Value;
            for i = 1:length(app.PositiveFeedbackForListBox.Items)
                if strcmp(app.PositiveFeedbackForListBox.Items{i}, value)
                    break;
                end
            end
            app.colours.feedbackSetting = i;
            app.colours.updateSettings();
        end

        % Value changed function: DefaultRoundingListBox
        function DefaultRoundingListBoxValueChanged(app, event)
            value = app.DefaultRoundingListBox.Value;
            for i = 1:length(app.DefaultRoundingListBox.Items)
                if strcmp(app.DefaultRoundingListBox.Items{i}, value)
                    break;
                end
            end
            app.settingChanged(7, {int2str(i)});
        end

        % Value changed function: BillofMaterialsEditField
        function BillofMaterialsEditFieldValueChanged(app, event)
            value = app.BillofMaterialsEditField.Value;
            app.weights{6} = int2str(value);
            app.updateWeights();
        end

        % Value changed function: CenterLineEditField
        function CenterLineEditFieldValueChanged(app, event)
            value = app.CenterLineEditField.Value;
            app.weights{2} = int2str(value);
            app.updateWeights();
        end

        % Value changed function: CenterMarkEditField
        function CenterMarkEditFieldValueChanged(app, event)
            value = app.CenterMarkEditField.Value;
            app.weights{3}= int2str(value);
            app.updateWeights();
        end

        % Value changed function: BalloonEditField
        function BalloonEditFieldValueChanged(app, event)
            value = app.BalloonEditField.Value;
            app.weights{5} = int2str(value);
            app.updateWeights();
        end

        % Value changed function: DimensionEditField
        function DimensionEditFieldValueChanged(app, event)
            value = app.DimensionEditField.Value;
            app.weights{1} = int2str(value);
            app.updateWeights();
        end

        % Value changed function: DatumEditField
        function DatumEditFieldValueChanged(app, event)
            value = app.DatumEditField.Value;
            app.weights{4} = int2str(value);
            app.updateWeights();
        end

        % Value changed function: ScoreTablePositionListBox
        function ScoreTablePositionListBoxValueChanged(app, event)
            value = app.ScoreTablePositionListBox.Value;
            for i = 1:length(app.ScoreTablePositionListBox.Items)
                if strcmp(app.ScoreTablePositionListBox.Items{i}, value)
                    break;
                end
            end
            app.colours.tablePosition = i;
            app.colours.updateSettings();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReportSettingsLabel = uilabel(app.UIFigure);
            app.ReportSettingsLabel.Text = 'Report Settings';
            app.CorrectionColoursLabel = uilabel(app.UIFigure);
            app.CorrectionColoursLabel.Text = 'Correction Colours';
            app.MissingLabel = uilabel(app.UIFigure);
            app.MissingLabel.Text = 'Missing';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.UnrecognizedLabel = uilabel(app.UIFigure);
            app.UnrecognizedLabel.Text = 'Unrecognized';
            app.ValueLabel = uilabel(app.UIFigure);
            app.ValueLabel.Text = 'Value';
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position';
            app.MiscLabel = uilabel(app.UIFigure);
            app.MiscLabel.Text = 'Misc';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '';
            app.ProfessorNameLabel = uilabel(app.UIFigure);
            app.ProfessorNameLabel.Text = 'Professor Name:';
            app.ProfessorNameTextArea = uitextarea(app.UIFigure);
            app.ProfessorNameTextArea.ValueChangedFcn = createCallbackFcn(app, @ProfessorNameTextAreaValueChanged, true);
            app.AssignmentNoTextAreaLabel = uilabel(app.UIFigure);
            app.AssignmentNoTextAreaLabel.Text = 'Assignment No:';
            app.AssignmentNoTextArea = uitextarea(app.UIFigure);
            app.AssignmentNoTextArea.ValueChangedFcn = createCallbackFcn(app, @AssignmentNoTextAreaValueChanged, true);
            app.CourseNameLabel = uilabel(app.UIFigure);
            app.CourseNameLabel.Text = 'Course Name:';
            app.CourseNameTextArea = uitextarea(app.UIFigure);
            app.CourseNameTextArea.ValueChangedFcn = createCallbackFcn(app, @CourseNameTextAreaValueChanged, true);
            app.CurrentSemesterLabel_2 = uilabel(app.UIFigure);
            app.CurrentSemesterLabel_2.Text = 'Current Semester:';
            app.CurrentSemesterTextArea = uitextarea(app.UIFigure);
            app.CurrentSemesterTextArea.ValueChangedFcn = createCallbackFcn(app, @CurrentSemesterTextAreaValueChanged, true);
            app.EmailTextAreaLabel = uilabel(app.UIFigure);
            app.EmailTextAreaLabel.Text = 'Email:';
            app.EmailTextArea = uitextarea(app.UIFigure);
            app.EmailTextArea.ValueChangedFcn = createCallbackFcn(app, @EmailTextAreaValueChanged, true);
            app.FontNameTextAreaLabel = uilabel(app.UIFigure);
            app.FontNameTextAreaLabel.Text = 'Font Name:';
            app.FontNameTextArea = uitextarea(app.UIFigure);
            app.FontNameTextArea.ValueChangedFcn = createCallbackFcn(app, @FontNameTextAreaValueChanged, true);
            app.FontSizeEditFieldLabel = uilabel(app.UIFigure);
            app.FontSizeEditFieldLabel.Text = 'Font Size:';
            app.FontSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FontSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FontSizeEditFieldValueChanged, true);
            app.AssignmentTotalEditFieldLabel = uilabel(app.UIFigure);
            app.AssignmentTotalEditFieldLabel.Text = 'Assignment Total:';
            app.AssignmentTotalEditField = uieditfield(app.UIFigure, 'numeric');
            app.AssignmentTotalEditField.ValueChangedFcn = createCallbackFcn(app, @AssignmentTotalEditFieldValueChanged, true);
            app.AssignmentTotalEditField.Value = 20;
            app.CorrectLabel = uilabel(app.UIFigure);
            app.CorrectLabel.Text = 'Correct';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '';
            app.CorrectionSettingsLabel = uilabel(app.UIFigure);
            app.CorrectionSettingsLabel.Text = 'Correction Settings';
            app.PositiveFeedbackForLabel = uilabel(app.UIFigure);
            app.PositiveFeedbackForLabel.Text = {'Positive'; 'Feedback For:'};
            app.PositiveFeedbackForListBox = uilistbox(app.UIFigure);
            app.PositiveFeedbackForListBox.Items = {'All Criterion', 'Criterion over 3 marks', 'No criterion'};
            app.PositiveFeedbackForListBox.ValueChangedFcn = createCallbackFcn(app, @PositiveFeedbackForListBoxValueChanged, true);
            app.PositiveFeedbackForListBox.Value = 'All Criterion';
            app.DefaultRoundingLabel = uilabel(app.UIFigure);
            app.DefaultRoundingLabel.Text = {'Default'; 'Rounding:'};
            app.DefaultRoundingListBox = uilistbox(app.UIFigure);
            app.DefaultRoundingListBox.Items = {'Round Half Up', 'Round Up', 'Round Down'};
            app.DefaultRoundingListBox.ValueChangedFcn = createCallbackFcn(app, @DefaultRoundingListBoxValueChanged, true);
            app.DefaultRoundingListBox.Value = 'Round Half Up';
            app.DefaultFeatureWeightsLabel = uilabel(app.UIFigure);
            app.DefaultFeatureWeightsLabel.Text = 'Default Feature Weights';
            app.BillofMaterialsLabel = uilabel(app.UIFigure);
            app.BillofMaterialsLabel.Text = 'Bill of Materials:';
            app.BillofMaterialsEditField = uieditfield(app.UIFigure, 'numeric');
            app.BillofMaterialsEditField.ValueChangedFcn = createCallbackFcn(app, @BillofMaterialsEditFieldValueChanged, true);
            app.CenterLineLabel = uilabel(app.UIFigure);
            app.CenterLineLabel.Text = 'Center Line:';
            app.CenterLineEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterLineEditField.ValueChangedFcn = createCallbackFcn(app, @CenterLineEditFieldValueChanged, true);
            app.CenterMarkEditFieldLabel = uilabel(app.UIFigure);
            app.CenterMarkEditFieldLabel.Text = 'Center Mark';
            app.CenterMarkEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterMarkEditField.ValueChangedFcn = createCallbackFcn(app, @CenterMarkEditFieldValueChanged, true);
            app.BalloonEditFieldLabel = uilabel(app.UIFigure);
            app.BalloonEditFieldLabel.Text = 'Balloon:';
            app.BalloonEditField = uieditfield(app.UIFigure, 'numeric');
            app.BalloonEditField.ValueChangedFcn = createCallbackFcn(app, @BalloonEditFieldValueChanged, true);
            app.DimensionEditFieldLabel = uilabel(app.UIFigure);
            app.DimensionEditFieldLabel.Text = 'Dimension:';
            app.DimensionEditField = uieditfield(app.UIFigure, 'numeric');
            app.DimensionEditField.ValueChangedFcn = createCallbackFcn(app, @DimensionEditFieldValueChanged, true);
            app.DatumEditFieldLabel = uilabel(app.UIFigure);
            app.DatumEditFieldLabel.Text = 'Datum:';
            app.DatumEditField = uieditfield(app.UIFigure, 'numeric');
            app.DatumEditField.ValueChangedFcn = createCallbackFcn(app, @DatumEditFieldValueChanged, true);
            app.ScoreTablePositionListBoxLabel = uilabel(app.UIFigure);
            app.ScoreTablePositionListBoxLabel.Text = {'Score Table'; 'Position:'};
            app.ScoreTablePositionListBox = uilistbox(app.UIFigure);
            app.ScoreTablePositionListBox.Items = {'Top Left', 'Top Mid/Right', 'Top Right', 'Bottom Left', 'Bottom Middle', 'Mid/Bottom Right'};
            app.ScoreTablePositionListBox.ValueChangedFcn = createCallbackFcn(app, @ScoreTablePositionListBoxValueChanged, true);
            app.ScoreTablePositionListBox.Value = 'Top Left';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SettingsGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end